classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        MainviewTab                     matlab.ui.container.Tab
        MainGrid                        matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        MapGridLayout                   matlab.ui.container.GridLayout
        GridLayout30                    matlab.ui.container.GridLayout
        DisableMapAutoscaleButton       matlab.ui.control.StateButton
        MapPanel                        matlab.ui.container.Panel
        TopInfoGridLayout               matlab.ui.container.GridLayout
        PolarisationControlGridLayout   matlab.ui.container.GridLayout
        PolarisationControlValuesGridLayout  matlab.ui.container.GridLayout
        EditpolarisationEditField       matlab.ui.control.NumericEditField
        EditpolarisationEditFieldLabel  matlab.ui.control.Label
        PolarisationcontrolLabel        matlab.ui.control.Label
        TrackerTargetAdditionalInfoGridLayout  matlab.ui.container.GridLayout
        TargetPositionGridLayout        matlab.ui.container.GridLayout
        GridLayout25_4                  matlab.ui.container.GridLayout
        TargetHeadingrelativetotrackerEditField  matlab.ui.control.NumericEditField
        HeadingrelativetotrackerEditFieldLabel  matlab.ui.control.Label
        TargetHeadingabsoluteEditField  matlab.ui.control.NumericEditField
        HeadingabsoluteEditFieldLabel   matlab.ui.control.Label
        TargetElevationEditField        matlab.ui.control.NumericEditField
        ElevationEditField_2Label       matlab.ui.control.Label
        TargetDistancestraightlineEditField  matlab.ui.control.NumericEditField
        DistancestraightlineEditFieldLabel  matlab.ui.control.Label
        TargetpositionLabel             matlab.ui.control.Label
        TrackerOrientationGridLayout    matlab.ui.container.GridLayout
        TrackerorientationLabel         matlab.ui.control.Label
        TrackerOrientationValuesGridLayout  matlab.ui.container.GridLayout
        TrackerPitchEditField           matlab.ui.control.NumericEditField
        PitchEditFieldLabel             matlab.ui.control.Label
        TrackerRollEditField            matlab.ui.control.NumericEditField
        RollEditFieldLabel              matlab.ui.control.Label
        TrackerYawEditField             matlab.ui.control.NumericEditField
        YawEditFieldLabel               matlab.ui.control.Label
        LeftPanel                       matlab.ui.container.Panel
        LeftPanelGridLayoutMain         matlab.ui.container.GridLayout
        LeftPanelGridLayout             matlab.ui.container.GridLayout
        TrackerControlGridLayout        matlab.ui.container.GridLayout
        TrackercontrolLabel             matlab.ui.control.Label
        TracklerControlButtonGridLayout  matlab.ui.container.GridLayout
        Button2                         matlab.ui.control.Button
        ZerooutazimuthButton            matlab.ui.control.Button
        ResetTrackerButton              matlab.ui.control.Button
        ZerooutpolarisationButton       matlab.ui.control.Button
        CalibrateButton                 matlab.ui.control.Button
        ActivatedeactivatemotorsButton  matlab.ui.control.StateButton
        LocationSourceGridLayoutMain    matlab.ui.container.GridLayout
        LocationSourceGridLayout        matlab.ui.container.GridLayout
        TargetLocationSourceGridLayout  matlab.ui.container.GridLayout
        TargetNewDataSourceSwitchGridLayout  matlab.ui.container.GridLayout
        TargetLocationSourceSwitch      matlab.ui.control.Switch
        TargetNewDataGridLayout         matlab.ui.container.GridLayout
        TargetNewDataLamp               matlab.ui.control.Lamp
        NewdataLamp_2Label_2            matlab.ui.control.Label
        TargetPositionControlGridLayout  matlab.ui.container.GridLayout
        TargetStepSizeGridLayout        matlab.ui.container.GridLayout
        TargetStepSizeRadioButton       matlab.ui.container.ButtonGroup
        TargetStep1000RadioButton       matlab.ui.control.RadioButton
        TargetStep100RadioButton        matlab.ui.control.RadioButton
        TargetStep10RadioButton         matlab.ui.control.RadioButton
        TargetStep1RadioButton          matlab.ui.control.RadioButton
        TargetPositionLatLongAltControlGridLayout  matlab.ui.container.GridLayout
        TargetAltEditGridLayout         matlab.ui.container.GridLayout
        TargetAltEditField              matlab.ui.control.NumericEditField
        LONGEditField_2Label_5          matlab.ui.control.Label
        TargetPositionButtonsGridLayout  matlab.ui.container.GridLayout
        TargetPositionRight             matlab.ui.control.Button
        TargetPositionDown              matlab.ui.control.Button
        TargetPositionLeft              matlab.ui.control.Button
        TargetPositionUp                matlab.ui.control.Button
        TargetLongEditGridLayout        matlab.ui.container.GridLayout
        TargetLongEditField             matlab.ui.control.NumericEditField
        LONGEditField_2Label_4          matlab.ui.control.Label
        TargetLatEditGridLayout         matlab.ui.container.GridLayout
        TargetLatEditField              matlab.ui.control.NumericEditField
        LATEditField_2Label_3           matlab.ui.control.Label
        TrackerLocationSourceGridLayout  matlab.ui.container.GridLayout
        TrackerNewDataSourceSwitchGridLayout  matlab.ui.container.GridLayout
        GridLayout18                    matlab.ui.container.GridLayout
        TrackerNewDataLamp              matlab.ui.control.Lamp
        NewdataLampLabel_2              matlab.ui.control.Label
        TrackerLocationSourceSwitch     matlab.ui.control.Switch
        TrackerPositionControlGridLayout  matlab.ui.container.GridLayout
        TrackerStepSizeGridLayout       matlab.ui.container.GridLayout
        TrackerStepSizeRadioButtonGroup  matlab.ui.container.ButtonGroup
        TrackerStep1000RadioButton      matlab.ui.control.RadioButton
        TrackerStep100RadioButton       matlab.ui.control.RadioButton
        TrackerStep10RadioButton        matlab.ui.control.RadioButton
        TrackerStep1RadioButton         matlab.ui.control.RadioButton
        TrackerPositionLatLongAltControlGridLayout  matlab.ui.container.GridLayout
        TrackerAltEditGridLayout        matlab.ui.container.GridLayout
        TrackerAltEditField             matlab.ui.control.NumericEditField
        ALTEditField_2Label             matlab.ui.control.Label
        TrackerPositionButtonsGridLayout  matlab.ui.container.GridLayout
        TrackerPositionRight            matlab.ui.control.Button
        TrackerPositionDown             matlab.ui.control.Button
        TrackerPositionLeft             matlab.ui.control.Button
        TrackerPositionUp               matlab.ui.control.Button
        TrackerLatGridLayout            matlab.ui.container.GridLayout
        TrackerLongEditField            matlab.ui.control.NumericEditField
        LONGEditField_4Label            matlab.ui.control.Label
        TrackerLatEditGridLayout        matlab.ui.container.GridLayout
        TrackerLatEditField             matlab.ui.control.NumericEditField
        LATEditField_2Label_2           matlab.ui.control.Label
        TargetLabel                     matlab.ui.control.Label
        TrackerLabel                    matlab.ui.control.Label
        LocationsourcesLabel            matlab.ui.control.Label
        StartStopControlGridLayout      matlab.ui.container.GridLayout
        GridLayout21                    matlab.ui.container.GridLayout
        TrackingSyncWithDataReadButton  matlab.ui.control.StateButton
        TrackingButton                  matlab.ui.control.StateButton
        GridLayout20                    matlab.ui.container.GridLayout
        TrackerInfoDisplaySyncwithTargGPSButton  matlab.ui.control.StateButton
        TrackerdatareadButton           matlab.ui.control.StateButton
        GridLayout19                    matlab.ui.container.GridLayout
        MapUpdateSyncwithTargGPSButton  matlab.ui.control.StateButton
        MapUpdateButton                 matlab.ui.control.StateButton
        UPDATEMapDisplayTIMERButton     matlab.ui.control.Button
        MapDisplayPeriodInputGridLayout  matlab.ui.container.GridLayout
        MapDisplayPeriodInputEditField  matlab.ui.control.NumericEditField
        RefreshRateEditField_5Label     matlab.ui.control.Label
        UPDATEInfoTIMERButton           matlab.ui.control.Button
        UPDATETrackingTIMERButton       matlab.ui.control.Button
        TrackerInfoPeriodInputGridLayout  matlab.ui.container.GridLayout
        TrackerInfoPeriodInputEditField  matlab.ui.control.NumericEditField
        RefreshRateEditField_4Label     matlab.ui.control.Label
        TrackerControlUpdatePeriodInputGridLayout  matlab.ui.container.GridLayout
        TrackerControlUpdatePeriodInputEditField  matlab.ui.control.NumericEditField
        RefreshRateEditField_6Label     matlab.ui.control.Label
        ConnectionGridLayout            matlab.ui.container.GridLayout
        TargetCOMGridLayout_3           matlab.ui.container.GridLayout
        SelectTargetsCOMportDropDown    matlab.ui.control.DropDown
        SelectTargetsCOMportDropDown_3Label  matlab.ui.control.Label
        GridLayout8_3                   matlab.ui.container.GridLayout
        TargetConnectLamp               matlab.ui.control.Lamp
        TargetDisconnectButton          matlab.ui.control.Button
        TargetConnectButton             matlab.ui.control.Button
        TargetCOMPortRefreshButton      matlab.ui.control.Button
        GridLayout8_2                   matlab.ui.container.GridLayout
        TrackerConnectLamp              matlab.ui.control.Lamp
        TrackerDisconnectButton         matlab.ui.control.Button
        TrackerConnectButton            matlab.ui.control.Button
        TrackerCOMPortRefreshButton     matlab.ui.control.Button
        BaseCOMGridLayout               matlab.ui.container.GridLayout
        SelectTrackersCOMportDropDown   matlab.ui.control.DropDown
        SelectTrackersCOMportDropDownLabel  matlab.ui.control.Label
        ManualcontrolTab                matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        GridLayout23                    matlab.ui.container.GridLayout
        GridLayout24                    matlab.ui.container.GridLayout
        GridLayout25                    matlab.ui.container.GridLayout
        GridLayout28                    matlab.ui.container.GridLayout
        GridLayout29_2                  matlab.ui.container.GridLayout
        AzEditField                     matlab.ui.control.NumericEditField
        AzEditFieldLabel                matlab.ui.control.Label
        GridLayout29                    matlab.ui.container.GridLayout
        ElevEditField                   matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        GridLayout26                    matlab.ui.container.GridLayout
        EnableManualControlButton       matlab.ui.control.StateButton
        StepSizeButtonGroup             matlab.ui.container.ButtonGroup
        Step20Button                    matlab.ui.control.ToggleButton
        Step5Button                     matlab.ui.control.ToggleButton
        Step1Button                     matlab.ui.control.ToggleButton
        GridLayout27                    matlab.ui.container.GridLayout
        ReturnHomeButton                matlab.ui.control.Button
        YawIncreaseButton               matlab.ui.control.Button
        ElevDecreaseButton              matlab.ui.control.Button
        YawDecreaseButton               matlab.ui.control.Button
        ElevIncreaseButton              matlab.ui.control.Button
        SettingsTab                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        
        rad2deg_const = 180/pi;

        TrackerPort
        TargetPort
        TrackerConnected = 0;
        TargetConnected = 0;

        TrackerManualControl = 0;
        
        %% Tracker/Target display points, geoobj
        TargetCurrentGPSLocation = [50.1032042 14.3926031 300] % [lat, long, alt]
        TrackerCurrentGPSLocation = [50.0982544 14.3885983 300]

        TargetDisplayGPSLocation = [50.1032042 14.3926031 300] % [lat, long, alt]
        TrackerDisplayGPSLocation = [50.0982544 14.3885983 300]

        %TargetDisplayLocation = [50.1032042 14.3926031]
        %TrackerDisplayLocation = [50.0982544 14.3885983]

        geomap_tracker
        geomap_target
        geomap_line
        geoAx

        DiableMapAutoscaleValue = 0;
        
        
        %% Step sizes
        manualTrackerPositionStepSize = 1e-4;
        manualTargetPositionStepSize = 1e-4;

        stepSizeAzElManual = 1;


        %% Timers
        TrackerPortWatchdogTimer 
        TargetPortWatchdogTimer

        TrackingTimer
        MapDisplayTimer
        TrackerInfoDisplayTimer
        %%tracker input msg
        TrackerInfoMsg

        %% Output data to tracker
        tracker_azimuth_offset = 0;
        tracker_elevation_offset = 0;
        tracker_polarisation_offset = 0;

        polarisation_input = 0;

        tracker_azimuth = 0;
        tracker_elevation = 0;
        tracker_polarisation = 0;

        %% Tracker - Target values
        TrackerTargetAirDistance = 0;
        TrackerTargetHeading = 0;
        TrackerTargetElevation = 0;

        %% Button states
        TrackerInfoDisplaySyncwithTargGPSButtonValue = 0;
    end
    
    methods (Access = private)
        %% Tracker
        % Functions to handle disconnecting and connecting TRACKER COM port
        function signalTrackerComDisconnected(app)
            app.TrackerConnectLamp.Color = 'R';

            app.TrackerLocationSourceSwitch.Enable = "off";
            %app.TrackerLocationSourceSwitch.Value = "User";

            app.TrackerConnected = 0;
            app.SelectTrackersCOMportDropDown.Items = {''};
            app.SelectTrackersCOMportDropDown.Value = {''};
            app.SelectTrackersCOMportDropDown.Placeholder = 'Disconnected';

            app.TrackerCOMPortRefreshButton.Enable = "on";
            app.TrackerConnectButton.Enable = "on";

            app.switchTrackerLocationSourceToManual
            
        end

        function signalTrackerComConnected(app)
            app.TrackerLocationSourceSwitch.Enable = "on";
            app.TrackerConnectLamp.Color = 'G';
            app.TrackerCOMPortRefreshButton.Enable = "off";
            app.TrackerConnectButton.Enable = "off";

            %app.switchTrackerLocationSourceToGPS
            %app.TrackerLocationSourceSwitch.Value = "GPS";
        end
        
        % Function to ping tracker periodically

        function pingTrackerSerialPort(app,src,event)
            persistent wd  % so we can stop/delete it inside itself
            if isempty(wd)
                % grab the timer handle the first time
                timers = timerfind;  
                % assume it's the only one or the one you just started
                wd = timers(end);
            end
        
            try
                % do something trivial that errors if port is gone:
                app.TrackerPort.NumBytesAvailable;

                app.TrackerConnected = 1;
                app.signalTrackerComConnected
                % (you could also try flush(s) or write(s,uint8(0));)
            catch
                % port went away!
                app.signalTrackerComDisconnected
                stop(wd);
                delete(wd);
            end
        end
        
        function switchTrackerLocationSourceToGPS(app)
            app.TrackerLocationSourceSwitch.Value = "GPS";
            app.disableManualTrackerLocationControl
        end

        function switchTrackerLocationSourceToManual(app)
            app.TrackerLocationSourceSwitch.Value = "User";
            app.enableManualTrackerLocationControl
        end
        
        % Function to enable lat long alt input in GUI
        function enableManualTrackerLocationControl(app)
            app.TrackerLongEditField.Enable = "on";
            app.TrackerLatEditField.Enable = "on";
            app.TrackerAltEditField.Enable = "on";

            app.TrackerPositionLeft.Enable = "on";
            app.TrackerPositionRight.Enable = "on";
            app.TrackerPositionUp.Enable = "on";
            app.TrackerPositionDown.Enable = "on";

            app.TrackerStepSizeRadioButtonGroup.Enable = "on";
        end

        function disableManualTrackerLocationControl(app)
            app.TrackerLongEditField.Enable = "off";
            app.TrackerLatEditField.Enable = "off";
            app.TrackerAltEditField.Enable = "off";

            app.TrackerPositionLeft.Enable = "off";
            app.TrackerPositionRight.Enable = "off";
            app.TrackerPositionUp.Enable = "off";
            app.TrackerPositionDown.Enable = "off";

            app.TrackerStepSizeRadioButtonGroup.Enable = "off";
        end


        %% TARGET
        % Functions to handle disconnecting and connecting TARGET COM port

        function signalTargetComDisconnected(app)
            app.TargetConnectLamp.Color = 'R';
            app.TargetLocationSourceSwitch.Enable = "off";
            app.TargetLocationSourceSwitch.Value = "User";
            app.TargetConnected = 0;
            app.SelectTargetsCOMportDropDown.Items = {''};
            app.SelectTargetsCOMportDropDown.Value = {''};
            app.SelectTargetsCOMportDropDown.Placeholder = 'Disconnected';

            app.TargetCOMPortRefreshButton.Enable = "on";
            app.TargetConnectButton.Enable = "on";

            app.switchTargetLocationSourceToManual
        end

        function signalTargetComConnected(app)
            app.TargetLocationSourceSwitch.Enable = "on";
            app.TargetConnectLamp.Color = 'G';
            app.TargetCOMPortRefreshButton.Enable = "off";
            app.TargetConnectButton.Enable = "off";

        end
        
        %% Function to ping target periodically
        function pingTargetSerialPort(app,src,event)
            persistent wd  % so we can stop/delete it inside itself
            if isempty(wd)
                % grab the timer handle the first time
                timers = timerfind;  
                % assume it's the only one or the one you just started
                wd = timers(end);
            end
        
            try
                % do something trivial that errors if port is gone:
                app.TargetPort.NumBytesAvailable;
                
                app.TargetConnected = 1;
                app.signalTargetComConnected
                % (you could also try flush(s) or write(s,uint8(0));)
            catch
                % port went away!
                app.signalTargetComDisconnected
                stop(wd);
                delete(wd);
            end
        end

        function switchTargetLocationSourceToGPS(app)
            app.TargetLocationSourceSwitch.Value = "GPS";
            app.disableManualTargetLocationControl;
        end

        function switchTargetLocationSourceToManual(app)
            app.TargetLocationSourceSwitch.Value = "User";
            app.enableManualTargetLocationControl
        end

        function enableManualTargetLocationControl(app)
            app.TargetLatEditField.Enable = "on";
            app.TargetLongEditField.Enable = "on";
            app.TargetAltEditField.Enable = "on";

            app.TargetPositionLeft.Enable = "on";
            app.TargetPositionRight.Enable = "on";
            app.TargetPositionUp.Enable = "on";
            app.TargetPositionDown.Enable = "on";

            app.TargetStepSizeRadioButton.Enable = "on";
        end

        function disableManualTargetLocationControl(app)
            app.TargetLatEditField.Enable = "off";
            app.TargetLongEditField.Enable = "off";
            app.TargetAltEditField.Enable = "off";

            app.TargetPositionLeft.Enable = "off";
            app.TargetPositionRight.Enable = "off";
            app.TargetPositionUp.Enable = "off";
            app.TargetPositionDown.Enable = "off";

            app.TargetStepSizeRadioButton.Enable = "off";
        end
        
        function SetupTargetNmeaCallback(app)
            %cleanupObj = onCleanup(@() cleanupResources(app.TargetPort));
            configureCallback( ...
                app.TargetPort, ...
                "terminator", ...
                @(src,evt) app.nmeaCallback(src, evt) ...
            );
            function cleanupResources(serialObj)
            
                try
                    % Disable callback and clear serial port object
                    configureCallback(serialObj, 'off');
                    clear serialObj;
                catch
                    warning('Failed to clear serial port.');
                end
            end
        end

        function nmeaCallback(app, src, ~)
            line = readline(src);
        
            % Only process GNGGA (fix data: lat, lon, altitude)
            if startsWith(line, '$GNGGA')
                tic
                disp("Target data!")
                fields = split(line, ',');

                if app.TargetLocationSourceSwitch.Value == "GPS"
                    % Latitude  (field 3 = ddmm.mmmm, 4 = N/S)
                    app.TargetCurrentGPSLocation(1) = dmToDecimal(fields{3}, fields{4});
                    % Longitude (field 5 = dddmm.mmmm, 6 = E/W)
                    app.TargetCurrentGPSLocation(2) = dmToDecimal(fields{5}, fields{6});
                    % Altitude  (field 10, in meters)
                    alt = str2double(fields{10});
                    if ~isnan(alt)
                        app.TargetCurrentGPSLocation(3) = alt;
                    end
            
                    % If GPS is the active source, update your UI fields
                
                    app.TargetLatEditField.Value  = app.TargetCurrentGPSLocation(1);
                    app.TargetLongEditField.Value = app.TargetCurrentGPSLocation(2);
                    app.TargetAltEditField.Value  = app.TargetCurrentGPSLocation(3);
                end

                %% reading from tracker
                toc
                if(app.TrackerInfoDisplaySyncwithTargGPSButtonValue && app.TrackerdatareadButton.Value)
                    app.TrackerReadOrientationAndGPS
                end
               
                if(app.MapUpdateSyncwithTargGPSButton.Value && app.MapUpdateButton.Value)
                    app.MapUpdateFunction;
                    
                end
            end
        
            %-------------------------------------------------------------------
            function dec = dmToDecimal(dmStr, dir)
                % Convert NMEA "ddmm.mmmm" + direction into decimal degrees
                val = str2double(dmStr);
                deg = floor(val/100);
                minu = val - deg*100;
                dec = deg + minu/60;
                if any(dir == ['S','W'])
                    dec = -dec;
                end
            end
        end


        %% End TARGET


        % function [arc_len, air_len, heading, base_elev_deg, target_elev_deg] = UpdateDistanceHeadingElevation(~, base, target)
        % 
        %     R = 6371000; %Earth's radius in metres
        % 
        %     %% Distance
        %     % Converting from deg to rad
        %     point1_rad = deg2rad(base(1:2));
        %     point2_rad = deg2rad(target(1:2));
        % 
        %     % Computing great angle between the points
        %     great_angle_rad = acos(sin(point1_rad(1)) * sin(point2_rad(1)) + cos(point1_rad(1)) * cos(point2_rad(1)) * cos(abs(point1_rad(2) - point2_rad(2))));
        % 
        %     % Adding asl height to earth's radius
        %     total_len_1 = R+base(3);
        %     total_len_2 = R+target(3);
        % 
        %     % Basic arc length (not useful)
        %     arc_len = R*great_angle_rad;
        % 
        %     % Distance between the points if you are an electromagnetic wave
        %     air_len = sqrt(total_len_1^2 + total_len_2^2 - 2*total_len_1*total_len_2*cos(great_angle_rad));
        % 
        % 
        % 
        %     %% Elevation at which you have to look at the other point
        %     beta_rad = (asin(total_len_1*sin(great_angle_rad)/air_len));
        %     alpha_rad = (pi - (great_angle_rad + beta_rad));
        % 
        %     base_elev_deg = 180/pi * (-pi/2 + alpha_rad);
        %     target_elev_deg = 180/pi * (-pi/2 + beta_rad);
        % 
        % 
        % 
        %     %% Heading computing
        %     % dlat = point2_rad(1) - point1_rad(1);
        %     dlon = point2_rad(2) - point1_rad(2);
        % 
        %     y = sin(dlon) * cos(point2_rad(1));
        %     x = cos(point1_rad(1)) * sin(point2_rad(1)) - sin(point1_rad(1)) * cos(point2_rad(1)) * cos(dlon);
        %     heading = atan2(y, x);
        % 
        %     % Convert heading to degrees
        %     heading = rad2deg(heading);
        % 
        %     % Normalize heading to 0-360 degrees
        %     if heading < 0
        %         heading = heading + 360;
        %     end
        % 
        % end

        function [air_len, heading, elev] = UpdateDistanceHeadingElevation(app,tracker, target)
            % airDistHeadElev Computes straight-line air distance, surface heading, and elevation angle
            %   [air_len, heading, elev] = airDistHeadElev(tracker, target)
            %
            % Inputs:
            %   tracker - [lat, lon, alt] of the tracker point
            %             lat, lon in degrees; alt in meters above sea level (can be negative)
            %   target  - [lat, lon, alt] of the target point
            %             lat, lon in degrees; alt in meters above sea level (can be negative)
            %
            % Outputs:
            %   air_len - straight-line distance between points in meters
            %   heading - initial bearing from tracker to target in degrees clockwise from north
            %   elev    - elevation angle above the local horizontal at tracker in degrees
            
            % WGS84 ellipsoid parameters
            a = 6378137.0;            % semi-major axis (m)
            f = 1/298.257223563;      % flattening
            e2 = f * (2 - f);         % eccentricity squared
            
            % Unpack inputs
            lat1 = deg2rad(tracker(1)); lon1 = deg2rad(tracker(2)); h1 = tracker(3);
            lat2 = deg2rad(target(1));  lon2 = deg2rad(target(2));  h2 = target(3);
            
            % Convert geodetic to ECEF
            % Radius of curvature in prime vertical
            N1 = a ./ sqrt(1 - e2 * sin(lat1).^2);
            N2 = a ./ sqrt(1 - e2 * sin(lat2).^2);
            
            % ECEF coordinates
            x1 = (N1 + h1) * cos(lat1) * cos(lon1);
            y1 = (N1 + h1) * cos(lat1) * sin(lon1);
            z1 = (N1 * (1 - e2) + h1) * sin(lat1);
            
            x2 = (N2 + h2) * cos(lat2) * cos(lon2);
            y2 = (N2 + h2) * cos(lat2) * sin(lon2);
            z2 = (N2 * (1 - e2) + h2) * sin(lat2);
            
            % Vector difference
            dX = x2 - x1;
            dY = y2 - y1;
            dZ = z2 - z1;
            
            % Straight-line distance
            air_len = sqrt(dX.^2 + dY.^2 + dZ.^2);
            
            % Construct local ENU basis at tracker
            % East
            e_x = -sin(lon1);
            %e_y
            e_y = cos(lon1);
            %e_z
            e_z = 0;
            
            % North
            n_x = -sin(lat1) * cos(lon1);
            n_y = -sin(lat1) * sin(lon1);
            n_z = cos(lat1);
            
            % Up
            u_x = cos(lat1) * cos(lon1);
            u_y = cos(lat1) * sin(lon1);
            u_z = sin(lat1);
            
            % Project difference onto ENU
            dE = e_x * dX + e_y * dY + e_z * dZ;
            dN = n_x * dX + n_y * dY + n_z * dZ;
            dU = u_x * dX + u_y * dY + u_z * dZ;
            
            % Heading: angle from north toward east
            heading = atan2d(dE, dN);
            % Normalize to [0,360)
            heading = mod(heading + 360, 360);
            
            % Elevation angle: angle above local horizontal
            horiz_dist = hypot(dE, dN);
            elev = atan2d(dU, horiz_dist);
        end


        function TrackingCallback(app, src, event)
            if ~app.TrackerManualControl
                app.ComputeAndSendTrackerRotation 
            end
        end
        function ComputeAndSendTrackerRotation(app)
            [app.TrackerTargetAirDistance, app.TrackerTargetHeading, app.TrackerTargetElevation] = UpdateDistanceHeadingElevation(app, app.TrackerDisplayGPSLocation, app.TargetDisplayGPSLocation);
            app.TargetDistancestraightlineEditField.Value = app.TrackerTargetAirDistance;
            app.TargetElevationEditField.Value = app.TrackerTargetElevation;
            app.TargetHeadingabsoluteEditField.Value = app.TrackerTargetHeading;

            app.tracker_azimuth = app.TrackerTargetHeading+app.tracker_azimuth_offset;
            app.tracker_elevation = app.TrackerTargetElevation + app.tracker_elevation_offset;
            app.tracker_polarisation = app.polarisation_input + app.tracker_polarisation_offset;

            app.moveTracker

        end
        function moveTracker(app)
            if ~isempty(app.TrackerPort)
                send_orientation(app.TrackerPort, [app.tracker_azimuth app.tracker_elevation app.tracker_polarisation])
            end
        end

        function MapUpdateCallback(app, src, event)
            app.MapUpdateFunction;
        end

        function TrackerInfoUpdateCallback(app, src, event)
            app.TrackerReadOrientationAndGPS;
        end

        function TrackerReadOrientationAndGPS(app)
            if(~isempty(app.TrackerPort))
                %orientation read
                flush(app.TrackerPort)
                app.TrackerInfoMsg = readTrackerInfo(app.TrackerPort);
                app.TrackerRollEditField.Value = app.TrackerInfoMsg.phirad*app.rad2deg_const;
                app.TrackerPitchEditField.Value = app.TrackerInfoMsg.thetarad*app.rad2deg_const;
                app.TrackerYawEditField.Value = app.TrackerInfoMsg.psirad*app.rad2deg_const;
                
                %GPS read
                flush(app.TrackerPort)
                [TC_gps_data, valid,~] = TC_read_gps(app.TrackerPort, 0);
                disp("Tracker GPS")
                toc
                if valid && app.TrackerLocationSourceSwitch.Value == "GPS"
                    app.TrackerCurrentGPSLocation(1) = TC_gps_data.lat;
                    app.TrackerCurrentGPSLocation(2) = TC_gps_data.lon;
                    app.TrackerCurrentGPSLocation(3) = TC_gps_data.hMSL;
                
                    app.TrackerLatEditField.Value = app.TrackerCurrentGPSLocation(1);
                    app.TrackerLongEditField.Value = app.TrackerCurrentGPSLocation(2);
                    app.TrackerAltEditField.Value = app.TrackerCurrentGPSLocation(3);
                end

                if(app.TrackingButton.Value == 1 && app.TrackingSyncWithDataReadButton.Value == 1)
                    app.ComputeAndSendTrackerRotation
                end
            end
            
        end


        function MapUpdateFunction(app)
            if(app.TrackerLocationSourceSwitch.Value == "User")
                app.TrackerDisplayGPSLocation(1) = app.TrackerLatEditField.Value;
                app.TrackerDisplayGPSLocation(2) = app.TrackerLongEditField.Value;
                app.TrackerDisplayGPSLocation(3) = app.TrackerAltEditField.Value;
            else
                app.TrackerDisplayGPSLocation = app.TrackerCurrentGPSLocation;
            end

            if (app.TargetLocationSourceSwitch.Value == "User")
                app.TargetDisplayGPSLocation(1) = app.TargetLatEditField.Value;
                app.TargetDisplayGPSLocation(2) = app.TargetLongEditField.Value;
                app.TargetDisplayGPSLocation(3) = app.TargetAltEditField.Value;
            else
                app.TargetDisplayGPSLocation = app.TargetCurrentGPSLocation;
            end


            app.geomap_tracker.LatitudeData = app.TrackerDisplayGPSLocation(1);
            app.geomap_tracker.LongitudeData = app.TrackerDisplayGPSLocation(2);
            
            app.geomap_target.LatitudeData = app.TargetDisplayGPSLocation(1);
            app.geomap_target.LongitudeData = app.TargetDisplayGPSLocation(2);

            app.geomap_line.LatitudeData = [app.TrackerLatEditField.Value app.TargetLatEditField.Value];
            app.geomap_line.LongitudeData = [app.TrackerLongEditField.Value app.TargetLongEditField.Value];
            if ~app.DiableMapAutoscaleValue
                app.MapAutoScale;
            end
            
        end
        function MapAutoScale(app)
            padding = 1e-3;
            geolimits(app.geoAx, ...
            [(min(app.geomap_line.LatitudeData)-0.5*abs(app.geomap_line.LatitudeData(1)- app.geomap_line.LatitudeData(2)))-padding, ...
            (max(app.geomap_line.LatitudeData)+0.5*abs(app.geomap_line.LatitudeData(1)- app.geomap_line.LatitudeData(2)))+padding], ...
            [(min(app.geomap_line.LongitudeData)-0.5*abs(app.geomap_line.LongitudeData(1)- app.geomap_line.LongitudeData(2)))-padding, ...
            (max(app.geomap_line.LongitudeData)+0.5*abs(app.geomap_line.LongitudeData(1)- app.geomap_line.LongitudeData(2)))+padding]) % Auto scaling
        
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TrackerLatEditField.Value = app.TrackerCurrentGPSLocation(1);            
            app.TrackerLongEditField.Value = app.TrackerCurrentGPSLocation(2);            
            app.TrackerAltEditField.Value = app.TrackerCurrentGPSLocation(3);

            app.TargetLatEditField.Value = app.TargetCurrentGPSLocation(1);            
            app.TargetLongEditField.Value = app.TargetCurrentGPSLocation(2);            
            app.TargetAltEditField.Value = app.TargetCurrentGPSLocation(3);            
            
            app.geoAx = geoaxes('Parent', app.MapPanel,'Position', [0.08 0.08 0.88 0.88]);
            geobasemap(app.geoAx, "streets");
            hold(app.geoAx, 'on');
            app.geomap_tracker = geoplot(app.geoAx, [app.TrackerDisplayGPSLocation(1)], [app.TrackerDisplayGPSLocation(2)], ...
                '-o', 'LineStyle', 'none','LineWidth', 2, 'MarkerSize', 8, 'Color', 'r');
            app.geomap_target = geoplot(app.geoAx, [app.TargetDisplayGPSLocation(1)], [app.TargetDisplayGPSLocation(2)], ...
                '-o', 'LineStyle', 'none', 'LineWidth', 2, 'MarkerSize', 8, 'Color', 'b');
            app.geomap_line = geoplot(app.geoAx, [app.TrackerDisplayGPSLocation(1) app.TargetDisplayGPSLocation(1)], [app.TrackerDisplayGPSLocation(2) app.TargetDisplayGPSLocation(2)], ...
                'Marker','none' , 'LineWidth', 2,"LineStyle", '--', 'MarkerSize', 8, 'Color', 'r');
            legend(app.geoAx, 'Tracker', 'Target', '')
            app.MapAutoScale;
        end

        % Callback function
        function base_location_source_fcn(app, event)
            value = app.base_loc_source.Value;
            
        end

        % Callback function
        function target_loc_sourceValueChanged(app, event)
            value = app.TargetLocationSourceSwitch.Value;
            switch value
                case "GPS"
                    app.target_LONGEditFieapp.panel_mapld.Enable = "off";
                    app.TargetLatEditField.Enable = "off";

                    app.TargetPositionLeft.Enable = "off";
                    app.TargetPositionRight.Enable = "off";
                    app.TargetPositionUp.Enable = "off";
                    app.TargetPositionDown.Enable = "off";

                    app.target_StepsizeButtonGroup.Enable = "off";
                case "User"
                    app.TargetLongEditField.Enable = "on";
                    app.TargetLatEditField.Enable = "on";

                    app.TargetPositionLeft.Enable = "on";
                    app.TargetPositionRight.Enable = "on";
                    app.TargetPositionUp.Enable = "on";
                    app.TargetPositionDown.Enable = "on";

                    app.target_StepsizeButtonGroup.Enable = "on";
            end
        end

        % Button pushed function: TrackerCOMPortRefreshButton
        function TrackerCOMPortRefreshButtonPushed(app, event)
            avalible_serial_ports = serialportlist;
            app.SelectTrackersCOMportDropDown.Items = avalible_serial_ports;
        end

        % Button pushed function: TrackerConnectButton
        function TrackerConnectButtonPushed(app, event)
            lastwarn('');
            try
                app.TrackerPort = TC_connect(app.SelectTrackersCOMportDropDown.Value);
                [warnMsg, warnId] = lastwarn;

                if ~isempty(warnMsg)
                    app.TrackerConnectLamp.Color='R';
                else
                    app.TrackerPortWatchdogTimer = timer( ...
                    'ExecutionMode',   'fixedSpacing', ...  % run regularly
                    'BusyMode','queue',...
                    'Period',          2.0,             ...  % every second
                    'TimerFcn',        @app.pingTrackerSerialPort ...
                    );
                    app.switchTrackerLocationSourceToGPS;
                    if strcmp(app.TrackerPortWatchdogTimer.Running,"off")
                        start(app.TrackerPortWatchdogTimer);
                    end
                end
            catch ME
                app.TrackerConnectLamp.Color='R';

            end
            

        end

        % Callback function: not associated with a component
        function PolarisationUpdateCallback(app, event)
            value = app.EditpolarisationEditField.Value;
            
        end

        % Value changed function: TrackerLocationSourceSwitch
        function TrackerLocationSourceSwitchValueChanged(app, event)
            value = app.TrackerLocationSourceSwitch.Value;
            switch value
                case "GPS"
                    app.switchTrackerLocationSourceToGPS
                case "User"
                    app.switchTrackerLocationSourceToManual
            end
        end

        % Button pushed function: TargetCOMPortRefreshButton
        function TargetCOMPortRefreshButtonPushed(app, event)
            avalible_serial_ports = serialportlist;
            app.SelectTargetsCOMportDropDown.Items = avalible_serial_ports;
        end

        % Button pushed function: TargetConnectButton
        function TargetConnectButtonPushed(app, event)
            lastwarn('');
            try
                app.TargetPort = Target_connect(app.SelectTargetsCOMportDropDown.Value);
                [warnMsg, warnId] = lastwarn;

                if ~isempty(warnMsg)
                    app.TargetConnectLamp.Color='R';
                else
                    app.TargetPortWatchdogTimer = timer( ...
                    'ExecutionMode',   'fixedSpacing', ...  % run regularly
                    'BusyMode','queue',...
                    'Period',          2.0,             ...  % every second
                    'TimerFcn',        @app.pingTargetSerialPort ...
                    );
                    app.switchTargetLocationSourceToGPS;
                    if strcmp(app.TargetPortWatchdogTimer.Running,"off")
                        start(app.TargetPortWatchdogTimer);
                    end
                    pause(1)
                    app.SetupTargetNmeaCallback
                end
            catch ME
                app.TargetConnectLamp.Color='R';

            end
        end

        % Button pushed function: TrackerDisconnectButton
        function TrackerDisconnectButtonPushed(app, event)
            app.TrackerPort.delete
            app.switchTrackerLocationSourceToManual
        end

        % Button pushed function: TargetDisconnectButton
        function TargetDisconnectButtonPushed(app, event)
            % try
            %     % Disable callback and clear serial port object
            %     configureCallback(serialObj, 'off');
            %     clear serialObj;
            % catch
            %     warning('Failed to clear serial port.');
            % end
            app.TargetPort.delete
            app.switchTargetLocationSourceToManual
        end

        % Value changed function: TargetLocationSourceSwitch
        function TargetLocationSourceSwitchValueChanged(app, event)
            value = app.TargetLocationSourceSwitch.Value;
            switch value
                case "GPS"
                    app.switchTargetLocationSourceToGPS
                case "User"
                    app.switchTargetLocationSourceToManual
            end
        end

        % Value changed function: TrackingButton
        function TrackingButtonValueChanged(app, event)
            value = app.TrackingButton.Value;
            switch value
                case 1 %start tracking with a given period
                    app.TrackerControlUpdatePeriodInputEditField.Enable = "off";
                    app.UPDATETrackingTIMERButton.Enable = "off";
                    app.TrackingSyncWithDataReadButton.Enable = "off";

                    if(app.TrackingSyncWithDataReadButton.Value == 0)
                        app.TrackingTimer = timer( ...
                        'ExecutionMode',   'fixedSpacing', ...  % run regularly
                        'BusyMode','drop',...
                        'Period',          app.TrackerControlUpdatePeriodInputEditField.Value, ...  
                        'TimerFcn',        @app.TrackingCallback ...
                        );
                        
                        
                        if strcmp(app.TrackingTimer.Running,"off")
                            start(app.TrackingTimer);
                        end
                    end
                case 0 %delete timer
                    app.TrackerControlUpdatePeriodInputEditField.Enable = "on";
                    app.UPDATETrackingTIMERButton.Enable = "on";
                    app.TrackingSyncWithDataReadButton.Enable = "on";
                    if(~isempty(app.TrackingTimer))
                        stop(app.TrackingTimer)
                    end
            end
        end

        % Value changed function: MapUpdateSyncwithTargGPSButton
        function MapUpdateSyncwithTargGPSButtonValueChanged(app, event)
            value = app.MapUpdateSyncwithTargGPSButton.Value;
            switch value
                case 1
                    app.MapDisplayPeriodInputEditField.Enable = "off";
                    app.UPDATEMapDisplayTIMERButton.Enable = "off";
                case 0
                    app.MapDisplayPeriodInputEditField.Enable = "on";
                    app.UPDATEMapDisplayTIMERButton.Enable = "on";
            end
        end

        % Value changed function: MapUpdateButton
        function MapUpdateButtonValueChanged(app, event)
            value = app.MapUpdateButton.Value;
            switch value
                case 1
                    app.MapDisplayPeriodInputEditField.Enable = "off";
                    app.UPDATEMapDisplayTIMERButton.Enable = "off";
                    app.MapUpdateSyncwithTargGPSButton.Enable = "off";
                    
                    if(app.MapUpdateSyncwithTargGPSButton.Value == 0)
                        app.MapDisplayTimer = timer( ...
                        'ExecutionMode',   'fixedSpacing', ...  % run regularly
                        'BusyMode','drop',...
                        'Period',          app.MapDisplayPeriodInputEditField.Value, ...  
                        'TimerFcn',        @app.MapUpdateCallback ...
                        );
                        
                        
                        if strcmp(app.MapDisplayTimer.Running,"off")
                            start(app.MapDisplayTimer);
                        end
                    end

                case 0
                    app.MapDisplayPeriodInputEditField.Enable = "on";
                    app.UPDATEMapDisplayTIMERButton.Enable = "on";
                    app.MapUpdateSyncwithTargGPSButton.Enable = "on";
                    if(~isempty(app.MapDisplayTimer))
                        stop(app.MapDisplayTimer)
                    end
            end
        end

        % Value changed function: TrackerdatareadButton
        function TrackerdatareadButtonValueChanged(app, event)
            value = app.TrackerdatareadButton.Value;
            switch value
                case 1 % run timer
                    app.TrackerInfoPeriodInputEditField.Enable = "off";
                    app.UPDATEInfoTIMERButton.Enable = "off";
                    app.TrackerInfoDisplaySyncwithTargGPSButton.Enable = "off";

                    if (app.TrackerInfoDisplaySyncwithTargGPSButton.Value == 0)
                        app.TrackerInfoDisplayTimer = timer( ...
                        'ExecutionMode',   'fixedSpacing', ...  % run regularly
                        'BusyMode','queue',...
                        'Period',          app.TrackerInfoPeriodInputEditField.Value, ...  
                        'TimerFcn',        @app.TrackerInfoUpdateCallback ...
                        );
                        
                        
                        if strcmp(app.TrackerInfoDisplayTimer.Running,"off")
                            start(app.TrackerInfoDisplayTimer);
                        end
                    end
                case 0 % stop timer
                    if(~isempty(app.TrackerInfoDisplayTimer))
                        stop(app.TrackerInfoDisplayTimer)
                    end
                    app.TrackerInfoPeriodInputEditField.Enable = "on";
                    app.UPDATEInfoTIMERButton.Enable = "on";
                    app.TrackerInfoDisplaySyncwithTargGPSButton.Enable = "on";

            end
        end

        % Value changed function: TrackerLatEditField
        function TrackerLatEditFieldValueChanged(app, event)
            value = app.TrackerLatEditField.Value;
            app.MapUpdateFunction;
        end

        % Value changed function: TrackerLongEditField
        function TrackerLongEditFieldValueChanged(app, event)
            value = app.TrackerLongEditField.Value;
            app.MapUpdateFunction;
        end

        % Value changed function: TrackerAltEditField
        function TrackerAltEditFieldValueChanged(app, event)
            value = app.TrackerAltEditField.Value;
            app.MapUpdateFunction;
        end

        % Value changed function: TargetLatEditField
        function TargetLatEditFieldValueChanged(app, event)
            value = app.TargetLatEditField.Value;
            app.MapUpdateFunction;
        end

        % Value changed function: TargetLongEditField
        function TargetLongEditFieldValueChanged(app, event)
            value = app.TargetLongEditField.Value;
            app.MapUpdateFunction;
        end

        % Value changed function: TargetAltEditField
        function TargetAltEditFieldValueChanged(app, event)
            value = app.TargetAltEditField.Value;
            app.MapUpdateFunction;
        end

        % Value changed function: TrackerInfoDisplaySyncwithTargGPSButton
        function TrackerInfoDisplaySyncwithTargGPSButtonValueChanged(app, event)
            app.TrackerInfoDisplaySyncwithTargGPSButtonValue = app.TrackerInfoDisplaySyncwithTargGPSButton.Value;
            switch app.TrackerInfoDisplaySyncwithTargGPSButtonValue
                case 1
                    app.TrackerInfoPeriodInputEditField.Enable = "off";
                    app.UPDATEInfoTIMERButton.Enable = "off";
                case 0
                    app.TrackerInfoPeriodInputEditField.Enable = "on";
                    app.UPDATEInfoTIMERButton.Enable = "on";
            end
        end

        % Value changed function: ActivatedeactivatemotorsButton
        function ActivatedeactivatemotorsButtonValueChanged(app, event)
            value = app.ActivatedeactivatemotorsButton.Value;
            if ~isempty(app.TrackerPort)
                switch value
                    case 1
                        motors_on(app.TrackerPort)
                    case 0
                        motors_off(app.TrackerPort)
                end
            end
        end

        % Button pushed function: ResetTrackerButton
        function ResetTrackerButtonPushed(app, event)
            app.ResetTrackerButton.Enable = "off";
            send_data(app.TrackerPort, 4) %Reset OPU
            pause(1)
            send_data(app.TrackerPort, 18) %Reset TC
            pause(1)
            app.ResetTrackerButton.Enable = "on";
        end

        % Value changed function: EnableManualControlButton
        function EnableManualControlButtonValueChanged(app, event)
            value = app.EnableManualControlButton.Value;
            switch value
                case 1
                    app.TrackerManualControl = 1;
                    app.ReturnHomeButton.Enable = "on";
                    app.YawIncreaseButton.Enable = "on";
                    app.YawDecreaseButton.Enable = "on";
                    app.ElevIncreaseButton.Enable = "on";
                    app.ElevDecreaseButton.Enable = "on";
                    app.StepSizeButtonGroup.Enable = "on";
                    app.ElevEditField.Enable = "on";
                    app.AzEditField.Enable = "on";
                    

                case 0
                    app.TrackerManualControl = 0;
                    app.ReturnHomeButton.Enable = "off";
                    app.YawIncreaseButton.Enable = "off";
                    app.YawDecreaseButton.Enable = "off";
                    app.ElevIncreaseButton.Enable = "off";
                    app.ElevDecreaseButton.Enable = "off";
                    app.StepSizeButtonGroup.Enable = "off";
                    app.ElevEditField.Enable = "off";
                    app.AzEditField.Enable = "off";

            end
        end

        % Selection changed function: StepSizeButtonGroup
        function StepSizeButtonGroupSelectionChanged(app, event)
            selectedButton = app.StepSizeButtonGroup.SelectedObject.Text;
            
            switch selectedButton
                case 'Step 1 °'
                    app.stepSizeAzElManual = 1;
                case 'Step 5 °'
                    app.stepSizeAzElManual = 5;
                case 'Step 20 °'
                    app.stepSizeAzElManual = 20;
            end
            

        end

        % Button pushed function: ElevIncreaseButton
        function ElevIncreaseButtonPushed(app, event)
            app.tracker_elevation = app.tracker_elevation + app.stepSizeAzElManual;
            app.moveTracker
            app.ElevEditField.Value = app.tracker_elevation;
            
        end

        % Button pushed function: ElevDecreaseButton
        function ElevDecreaseButtonPushed(app, event)
            app.tracker_elevation = app.tracker_elevation - app.stepSizeAzElManual;
            app.moveTracker
            app.ElevEditField.Value = app.tracker_elevation;
            
        end

        % Button pushed function: YawIncreaseButton
        function YawIncreaseButtonPushed(app, event)
            app.tracker_azimuth = app.tracker_azimuth + app.stepSizeAzElManual;
            app.moveTracker
            
            app.AzEditField.Value = app.tracker_azimuth;
        end

        % Button pushed function: YawDecreaseButton
        function YawDecreaseButtonPushed(app, event)
            app.tracker_azimuth = app.tracker_azimuth - app.stepSizeAzElManual;
            app.moveTracker
            
            app.AzEditField.Value = app.tracker_azimuth;
        end

        % Button pushed function: ReturnHomeButton
        function ReturnHomeButtonPushed(app, event)
            app.tracker_azimuth = 0;
            app.tracker_elevation = 0;
            app.ElevEditField.Value = app.tracker_elevation;
            app.AzEditField.Value = app.tracker_azimuth;
            app.moveTracker
        end

        % Button pushed function: TrackerPositionUp
        function TrackerPositionUpButtonPushed(app, event)
            app.TrackerLatEditField.Value = app.TrackerLatEditField.Value+app.manualTrackerPositionStepSize;
            app.MapUpdateFunction
        end

        % Button pushed function: TrackerPositionRight
        function TrackerPositionRightButtonPushed(app, event)
            app.TrackerLongEditField.Value = app.TrackerLongEditField.Value+app.manualTrackerPositionStepSize;
            app.MapUpdateFunction
        end

        % Button pushed function: TrackerPositionDown
        function TrackerPositionDownButtonPushed(app, event)
            app.TrackerLatEditField.Value = app.TrackerLatEditField.Value-app.manualTrackerPositionStepSize;
            app.MapUpdateFunction
        end

        % Button pushed function: TrackerPositionLeft
        function TrackerPositionLeftButtonPushed(app, event)
            app.TrackerLongEditField.Value = app.TrackerLongEditField.Value-app.manualTrackerPositionStepSize;
            app.MapUpdateFunction
        end

        % Selection changed function: TrackerStepSizeRadioButtonGroup
        function TrackerStepSizeRadioButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrackerStepSizeRadioButtonGroup.SelectedObject.Text;
            switch selectedButton
                case "1x"
                    app.manualTrackerPositionStepSize = 1*1e-4;
                case "10x"
                    app.manualTrackerPositionStepSize = 10*1e-4;
                case "100x"
                    app.manualTrackerPositionStepSize = 100*1e-4;
                case "1000x"
                    app.manualTrackerPositionStepSize = 1000*1e-4;
            end
        end

        % Value changed function: DisableMapAutoscaleButton
        function DisableMapAutoscaleButtonValueChanged(app, event)
            app.DiableMapAutoscaleValue = app.DisableMapAutoscaleButton.Value;
            
        end

        % Button pushed function: TargetPositionUp
        function TargetPositionUpButtonPushed(app, event)
            app.TargetLatEditField.Value = app.TargetLatEditField.Value+app.manualTargetPositionStepSize;
            app.MapUpdateFunction
        end

        % Button pushed function: TargetPositionRight
        function TargetPositionRightButtonPushed(app, event)
            app.TargetLongEditField.Value = app.TargetLongEditField.Value+app.manualTargetPositionStepSize;
            app.MapUpdateFunction
        end

        % Button pushed function: TargetPositionDown
        function TargetPositionDownButtonPushed(app, event)
            app.TargetLatEditField.Value = app.TargetLatEditField.Value-app.manualTargetPositionStepSize;
            app.MapUpdateFunction
        end

        % Button pushed function: TargetPositionLeft
        function TargetPositionLeftButtonPushed(app, event)
            app.TargetLongEditField.Value = app.TargetLongEditField.Value-app.manualTargetPositionStepSize;
            app.MapUpdateFunction
        end

        % Selection changed function: TargetStepSizeRadioButton
        function TargetStepSizeRadioButtonSelectionChanged(app, event)
            selectedButton = app.TargetStepSizeRadioButton.SelectedObject.Text;
            switch selectedButton
                case "1x"
                    app.manualTargetPositionStepSize = 1*1e-4;
                case "10x"
                    app.manualTargetPositionStepSize = 10*1e-4;
                case "100x"
                    app.manualTargetPositionStepSize = 100*1e-4;
                case "1000x"
                    app.manualTargetPositionStepSize = 1000*1e-4;
            end
        end

        % Value changed function: EditpolarisationEditField
        function EditpolarisationEditFieldValueChanged(app, event)
            value = app.EditpolarisationEditField.Value;
            app.tracker_polarisation = value;
            app.moveTracker
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            startCalibrationProcedure(app.TrackerPort)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MainviewTab = uitab(app.TabGroup);
            app.MainviewTab.Title = 'Main view';
            app.MainGrid = uigridlayout(app.MainviewTab);
            app.LeftPanel = uipanel(app.MainGrid);
            app.LeftPanelGridLayoutMain = uigridlayout(app.LeftPanel);
            app.LeftPanelGridLayout = uigridlayout(app.LeftPanelGridLayoutMain);
            app.ConnectionGridLayout = uigridlayout(app.LeftPanelGridLayout);
            app.BaseCOMGridLayout = uigridlayout(app.ConnectionGridLayout);
            app.SelectTrackersCOMportDropDownLabel = uilabel(app.BaseCOMGridLayout);
            app.SelectTrackersCOMportDropDownLabel.Text = 'Select Tracker''s COM port';
            app.SelectTrackersCOMportDropDown = uidropdown(app.BaseCOMGridLayout);
            app.SelectTrackersCOMportDropDown.Items = {};
            app.SelectTrackersCOMportDropDown.Placeholder = 'Press Refresh';
            app.SelectTrackersCOMportDropDown.Value = {};
            app.GridLayout8_2 = uigridlayout(app.ConnectionGridLayout);
            app.TrackerCOMPortRefreshButton = uibutton(app.GridLayout8_2, 'push');
            app.TrackerCOMPortRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @TrackerCOMPortRefreshButtonPushed, true);
            app.TrackerCOMPortRefreshButton.Text = 'Refresh';
            app.TrackerConnectButton = uibutton(app.GridLayout8_2, 'push');
            app.TrackerConnectButton.ButtonPushedFcn = createCallbackFcn(app, @TrackerConnectButtonPushed, true);
            app.TrackerConnectButton.Text = 'Connect';
            app.TrackerDisconnectButton = uibutton(app.GridLayout8_2, 'push');
            app.TrackerDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @TrackerDisconnectButtonPushed, true);
            app.TrackerDisconnectButton.Text = 'Disconnect';
            app.TrackerConnectLamp = uilamp(app.GridLayout8_2);
            app.GridLayout8_3 = uigridlayout(app.ConnectionGridLayout);
            app.TargetCOMPortRefreshButton = uibutton(app.GridLayout8_3, 'push');
            app.TargetCOMPortRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @TargetCOMPortRefreshButtonPushed, true);
            app.TargetCOMPortRefreshButton.Text = 'Refresh';
            app.TargetConnectButton = uibutton(app.GridLayout8_3, 'push');
            app.TargetConnectButton.ButtonPushedFcn = createCallbackFcn(app, @TargetConnectButtonPushed, true);
            app.TargetConnectButton.Text = 'Connect';
            app.TargetDisconnectButton = uibutton(app.GridLayout8_3, 'push');
            app.TargetDisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @TargetDisconnectButtonPushed, true);
            app.TargetDisconnectButton.Text = 'Disconnect';
            app.TargetConnectLamp = uilamp(app.GridLayout8_3);
            app.TargetCOMGridLayout_3 = uigridlayout(app.ConnectionGridLayout);
            app.SelectTargetsCOMportDropDown_3Label = uilabel(app.TargetCOMGridLayout_3);
            app.SelectTargetsCOMportDropDown_3Label.Text = 'Select Target''s COM port';
            app.SelectTargetsCOMportDropDown = uidropdown(app.TargetCOMGridLayout_3);
            app.SelectTargetsCOMportDropDown.Items = {};
            app.SelectTargetsCOMportDropDown.Placeholder = 'Press Refresh';
            app.SelectTargetsCOMportDropDown.Value = {};
            app.StartStopControlGridLayout = uigridlayout(app.LeftPanelGridLayout);
            app.TrackerControlUpdatePeriodInputGridLayout = uigridlayout(app.StartStopControlGridLayout);
            app.RefreshRateEditField_6Label = uilabel(app.TrackerControlUpdatePeriodInputGridLayout);
            app.RefreshRateEditField_6Label.Text = {'Refresh'; 'Rate'};
            app.TrackerControlUpdatePeriodInputEditField = uieditfield(app.TrackerControlUpdatePeriodInputGridLayout, 'numeric');
            app.TrackerControlUpdatePeriodInputEditField.Limits = [0 2];
            app.TrackerControlUpdatePeriodInputEditField.ValueDisplayFormat = '%11.4g s';
            app.TrackerControlUpdatePeriodInputEditField.Value = 1;
            app.TrackerInfoPeriodInputGridLayout = uigridlayout(app.StartStopControlGridLayout);
            app.RefreshRateEditField_4Label = uilabel(app.TrackerInfoPeriodInputGridLayout);
            app.RefreshRateEditField_4Label.Text = {'Refresh'; 'Rate'};
            app.TrackerInfoPeriodInputEditField = uieditfield(app.TrackerInfoPeriodInputGridLayout, 'numeric');
            app.TrackerInfoPeriodInputEditField.Limits = [0 10];
            app.TrackerInfoPeriodInputEditField.ValueDisplayFormat = '%11.4g s';
            app.TrackerInfoPeriodInputEditField.Value = 1;
            app.UPDATETrackingTIMERButton = uibutton(app.StartStopControlGridLayout, 'push');
            app.UPDATETrackingTIMERButton.Text = {'UPDATE'; 'TIMER'};
            app.UPDATEInfoTIMERButton = uibutton(app.StartStopControlGridLayout, 'push');
            app.UPDATEInfoTIMERButton.Text = {'UPDATE'; 'TIMER'};
            app.MapDisplayPeriodInputGridLayout = uigridlayout(app.StartStopControlGridLayout);
            app.RefreshRateEditField_5Label = uilabel(app.MapDisplayPeriodInputGridLayout);
            app.RefreshRateEditField_5Label.Text = {'Refresh'; 'Rate'};
            app.MapDisplayPeriodInputEditField = uieditfield(app.MapDisplayPeriodInputGridLayout, 'numeric');
            app.MapDisplayPeriodInputEditField.Limits = [0 10];
            app.MapDisplayPeriodInputEditField.ValueDisplayFormat = '%11.4g s';
            app.MapDisplayPeriodInputEditField.Value = 1;
            app.UPDATEMapDisplayTIMERButton = uibutton(app.StartStopControlGridLayout, 'push');
            app.UPDATEMapDisplayTIMERButton.Text = {'UPDATE'; 'TIMER'};
            app.GridLayout19 = uigridlayout(app.StartStopControlGridLayout);
            app.MapUpdateButton = uibutton(app.GridLayout19, 'state');
            app.MapUpdateButton.ValueChangedFcn = createCallbackFcn(app, @MapUpdateButtonValueChanged, true);
            app.MapUpdateButton.Text = 'Map Update';
            app.MapUpdateSyncwithTargGPSButton = uibutton(app.GridLayout19, 'state');
            app.MapUpdateSyncwithTargGPSButton.ValueChangedFcn = createCallbackFcn(app, @MapUpdateSyncwithTargGPSButtonValueChanged, true);
            app.MapUpdateSyncwithTargGPSButton.Text = {'Sync with'; 'Targ. GPS'};
            app.GridLayout20 = uigridlayout(app.StartStopControlGridLayout);
            app.TrackerdatareadButton = uibutton(app.GridLayout20, 'state');
            app.TrackerdatareadButton.ValueChangedFcn = createCallbackFcn(app, @TrackerdatareadButtonValueChanged, true);
            app.TrackerdatareadButton.Text = {'Tracker data'; 'read'};
            app.TrackerInfoDisplaySyncwithTargGPSButton = uibutton(app.GridLayout20, 'state');
            app.TrackerInfoDisplaySyncwithTargGPSButton.ValueChangedFcn = createCallbackFcn(app, @TrackerInfoDisplaySyncwithTargGPSButtonValueChanged, true);
            app.TrackerInfoDisplaySyncwithTargGPSButton.Text = {'Sync with'; 'Targ. GPS'};
            app.GridLayout21 = uigridlayout(app.StartStopControlGridLayout);
            app.TrackingButton = uibutton(app.GridLayout21, 'state');
            app.TrackingButton.ValueChangedFcn = createCallbackFcn(app, @TrackingButtonValueChanged, true);
            app.TrackingButton.Text = 'Tracking';
            app.TrackingSyncWithDataReadButton = uibutton(app.GridLayout21, 'state');
            app.TrackingSyncWithDataReadButton.Text = {'Sync with'; 'Data Read'};
            app.LocationSourceGridLayoutMain = uigridlayout(app.LeftPanelGridLayout);
            app.LocationSourceGridLayout = uigridlayout(app.LocationSourceGridLayoutMain);
            app.LocationsourcesLabel = uilabel(app.LocationSourceGridLayout);
            app.LocationsourcesLabel.Text = 'Location sources';
            app.TrackerLabel = uilabel(app.LocationSourceGridLayout);
            app.TrackerLabel.Text = 'Tracker';
            app.TargetLabel = uilabel(app.LocationSourceGridLayout);
            app.TargetLabel.Text = 'Target';
            app.TrackerLocationSourceGridLayout = uigridlayout(app.LocationSourceGridLayout);
            app.TrackerPositionControlGridLayout = uigridlayout(app.TrackerLocationSourceGridLayout);
            app.TrackerPositionLatLongAltControlGridLayout = uigridlayout(app.TrackerPositionControlGridLayout);
            app.TrackerLatEditGridLayout = uigridlayout(app.TrackerPositionLatLongAltControlGridLayout);
            app.LATEditField_2Label_2 = uilabel(app.TrackerLatEditGridLayout);
            app.LATEditField_2Label_2.Text = 'LAT';
            app.TrackerLatEditField = uieditfield(app.TrackerLatEditGridLayout, 'numeric');
            app.TrackerLatEditField.Limits = [0 Inf];
            app.TrackerLatEditField.ValueDisplayFormat = '%2.7f N';
            app.TrackerLatEditField.AllowEmpty = 'on';
            app.TrackerLatEditField.ValueChangedFcn = createCallbackFcn(app, @TrackerLatEditFieldValueChanged, true);
            app.TrackerLatGridLayout = uigridlayout(app.TrackerPositionLatLongAltControlGridLayout);
            app.LONGEditField_4Label = uilabel(app.TrackerLatGridLayout);
            app.LONGEditField_4Label.Text = 'LONG';
            app.TrackerLongEditField = uieditfield(app.TrackerLatGridLayout, 'numeric');
            app.TrackerLongEditField.Limits = [0 Inf];
            app.TrackerLongEditField.ValueDisplayFormat = '%2.7f E';
            app.TrackerLongEditField.AllowEmpty = 'on';
            app.TrackerLongEditField.ValueChangedFcn = createCallbackFcn(app, @TrackerLongEditFieldValueChanged, true);
            app.TrackerPositionButtonsGridLayout = uigridlayout(app.TrackerPositionLatLongAltControlGridLayout);
            app.TrackerPositionUp = uibutton(app.TrackerPositionButtonsGridLayout, 'push');
            app.TrackerPositionUp.ButtonPushedFcn = createCallbackFcn(app, @TrackerPositionUpButtonPushed, true);
            app.TrackerPositionUp.Text = '↑';
            app.TrackerPositionLeft = uibutton(app.TrackerPositionButtonsGridLayout, 'push');
            app.TrackerPositionLeft.ButtonPushedFcn = createCallbackFcn(app, @TrackerPositionLeftButtonPushed, true);
            app.TrackerPositionLeft.Text = '←';
            app.TrackerPositionDown = uibutton(app.TrackerPositionButtonsGridLayout, 'push');
            app.TrackerPositionDown.ButtonPushedFcn = createCallbackFcn(app, @TrackerPositionDownButtonPushed, true);
            app.TrackerPositionDown.Text = '↓';
            app.TrackerPositionRight = uibutton(app.TrackerPositionButtonsGridLayout, 'push');
            app.TrackerPositionRight.ButtonPushedFcn = createCallbackFcn(app, @TrackerPositionRightButtonPushed, true);
            app.TrackerPositionRight.Text = '→';
            app.TrackerAltEditGridLayout = uigridlayout(app.TrackerPositionLatLongAltControlGridLayout);
            app.ALTEditField_2Label = uilabel(app.TrackerAltEditGridLayout);
            app.ALTEditField_2Label.Text = 'ALT';
            app.TrackerAltEditField = uieditfield(app.TrackerAltEditGridLayout, 'numeric');
            app.TrackerAltEditField.ValueDisplayFormat = '%5.2f m a. s. l';
            app.TrackerAltEditField.AllowEmpty = 'on';
            app.TrackerAltEditField.ValueChangedFcn = createCallbackFcn(app, @TrackerAltEditFieldValueChanged, true);
            app.TrackerStepSizeGridLayout = uigridlayout(app.TrackerPositionControlGridLayout);
            app.TrackerStepSizeRadioButtonGroup = uibuttongroup(app.TrackerStepSizeGridLayout);
            app.TrackerStepSizeRadioButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrackerStepSizeRadioButtonGroupSelectionChanged, true);
            app.TrackerStepSizeRadioButtonGroup.Title = 'Step size';
            app.TrackerStep1RadioButton = uiradiobutton(app.TrackerStepSizeRadioButtonGroup);
            app.TrackerStep1RadioButton.Text = '1x';
            app.TrackerStep1RadioButton.Value = true;
            app.TrackerStep10RadioButton = uiradiobutton(app.TrackerStepSizeRadioButtonGroup);
            app.TrackerStep10RadioButton.Text = '10x';
            app.TrackerStep100RadioButton = uiradiobutton(app.TrackerStepSizeRadioButtonGroup);
            app.TrackerStep100RadioButton.Text = '100x';
            app.TrackerStep1000RadioButton = uiradiobutton(app.TrackerStepSizeRadioButtonGroup);
            app.TrackerStep1000RadioButton.Text = '1000x';
            app.TrackerNewDataSourceSwitchGridLayout = uigridlayout(app.TrackerLocationSourceGridLayout);
            app.TrackerLocationSourceSwitch = uiswitch(app.TrackerNewDataSourceSwitchGridLayout, 'slider');
            app.TrackerLocationSourceSwitch.Items = {'GPS', 'User'};
            app.TrackerLocationSourceSwitch.ValueChangedFcn = createCallbackFcn(app, @TrackerLocationSourceSwitchValueChanged, true);
            app.TrackerLocationSourceSwitch.Enable = 'off';
            app.TrackerLocationSourceSwitch.Value = 'User';
            app.GridLayout18 = uigridlayout(app.TrackerNewDataSourceSwitchGridLayout);
            app.NewdataLampLabel_2 = uilabel(app.GridLayout18);
            app.NewdataLampLabel_2.Text = 'New data';
            app.TrackerNewDataLamp = uilamp(app.GridLayout18);
            app.TargetLocationSourceGridLayout = uigridlayout(app.LocationSourceGridLayout);
            app.TargetPositionControlGridLayout = uigridlayout(app.TargetLocationSourceGridLayout);
            app.TargetPositionLatLongAltControlGridLayout = uigridlayout(app.TargetPositionControlGridLayout);
            app.TargetLatEditGridLayout = uigridlayout(app.TargetPositionLatLongAltControlGridLayout);
            app.LATEditField_2Label_3 = uilabel(app.TargetLatEditGridLayout);
            app.LATEditField_2Label_3.Text = 'LAT';
            app.TargetLatEditField = uieditfield(app.TargetLatEditGridLayout, 'numeric');
            app.TargetLatEditField.ValueDisplayFormat = '%2.7f N';
            app.TargetLatEditField.ValueChangedFcn = createCallbackFcn(app, @TargetLatEditFieldValueChanged, true);
            app.TargetLongEditGridLayout = uigridlayout(app.TargetPositionLatLongAltControlGridLayout);
            app.LONGEditField_2Label_4 = uilabel(app.TargetLongEditGridLayout);
            app.LONGEditField_2Label_4.Text = 'LONG';
            app.TargetLongEditField = uieditfield(app.TargetLongEditGridLayout, 'numeric');
            app.TargetLongEditField.ValueDisplayFormat = '%2.7f E';
            app.TargetLongEditField.ValueChangedFcn = createCallbackFcn(app, @TargetLongEditFieldValueChanged, true);
            app.TargetPositionButtonsGridLayout = uigridlayout(app.TargetPositionLatLongAltControlGridLayout);
            app.TargetPositionUp = uibutton(app.TargetPositionButtonsGridLayout, 'push');
            app.TargetPositionUp.ButtonPushedFcn = createCallbackFcn(app, @TargetPositionUpButtonPushed, true);
            app.TargetPositionUp.Text = '↑';
            app.TargetPositionLeft = uibutton(app.TargetPositionButtonsGridLayout, 'push');
            app.TargetPositionLeft.ButtonPushedFcn = createCallbackFcn(app, @TargetPositionLeftButtonPushed, true);
            app.TargetPositionLeft.Text = '←';
            app.TargetPositionDown = uibutton(app.TargetPositionButtonsGridLayout, 'push');
            app.TargetPositionDown.ButtonPushedFcn = createCallbackFcn(app, @TargetPositionDownButtonPushed, true);
            app.TargetPositionDown.Text = '↓';
            app.TargetPositionRight = uibutton(app.TargetPositionButtonsGridLayout, 'push');
            app.TargetPositionRight.ButtonPushedFcn = createCallbackFcn(app, @TargetPositionRightButtonPushed, true);
            app.TargetPositionRight.Text = '→';
            app.TargetAltEditGridLayout = uigridlayout(app.TargetPositionLatLongAltControlGridLayout);
            app.LONGEditField_2Label_5 = uilabel(app.TargetAltEditGridLayout);
            app.LONGEditField_2Label_5.Text = 'ALT';
            app.TargetAltEditField = uieditfield(app.TargetAltEditGridLayout, 'numeric');
            app.TargetAltEditField.ValueDisplayFormat = '%5.2f m a. s. l';
            app.TargetAltEditField.ValueChangedFcn = createCallbackFcn(app, @TargetAltEditFieldValueChanged, true);
            app.TargetStepSizeGridLayout = uigridlayout(app.TargetPositionControlGridLayout);
            app.TargetStepSizeRadioButton = uibuttongroup(app.TargetStepSizeGridLayout);
            app.TargetStepSizeRadioButton.SelectionChangedFcn = createCallbackFcn(app, @TargetStepSizeRadioButtonSelectionChanged, true);
            app.TargetStepSizeRadioButton.Title = 'Step size';
            app.TargetStep1RadioButton = uiradiobutton(app.TargetStepSizeRadioButton);
            app.TargetStep1RadioButton.Text = '1x';
            app.TargetStep1RadioButton.Value = true;
            app.TargetStep10RadioButton = uiradiobutton(app.TargetStepSizeRadioButton);
            app.TargetStep10RadioButton.Text = '10x';
            app.TargetStep100RadioButton = uiradiobutton(app.TargetStepSizeRadioButton);
            app.TargetStep100RadioButton.Text = '100x';
            app.TargetStep1000RadioButton = uiradiobutton(app.TargetStepSizeRadioButton);
            app.TargetStep1000RadioButton.Text = '1000x';
            app.TargetNewDataSourceSwitchGridLayout = uigridlayout(app.TargetLocationSourceGridLayout);
            app.TargetNewDataGridLayout = uigridlayout(app.TargetNewDataSourceSwitchGridLayout);
            app.NewdataLamp_2Label_2 = uilabel(app.TargetNewDataGridLayout);
            app.NewdataLamp_2Label_2.Text = 'New data';
            app.TargetNewDataLamp = uilamp(app.TargetNewDataGridLayout);
            app.TargetLocationSourceSwitch = uiswitch(app.TargetNewDataSourceSwitchGridLayout, 'slider');
            app.TargetLocationSourceSwitch.Items = {'GPS', 'User'};
            app.TargetLocationSourceSwitch.ValueChangedFcn = createCallbackFcn(app, @TargetLocationSourceSwitchValueChanged, true);
            app.TargetLocationSourceSwitch.Enable = 'off';
            app.TargetLocationSourceSwitch.Value = 'User';
            app.TrackerControlGridLayout = uigridlayout(app.LeftPanelGridLayout);
            app.TracklerControlButtonGridLayout = uigridlayout(app.TrackerControlGridLayout);
            app.ActivatedeactivatemotorsButton = uibutton(app.TracklerControlButtonGridLayout, 'state');
            app.ActivatedeactivatemotorsButton.ValueChangedFcn = createCallbackFcn(app, @ActivatedeactivatemotorsButtonValueChanged, true);
            app.ActivatedeactivatemotorsButton.Text = 'Activate/deactivate motors';
            app.CalibrateButton = uibutton(app.TracklerControlButtonGridLayout, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.ZerooutpolarisationButton = uibutton(app.TracklerControlButtonGridLayout, 'push');
            app.ZerooutpolarisationButton.Text = 'Zero out polarisation';
            app.ResetTrackerButton = uibutton(app.TracklerControlButtonGridLayout, 'push');
            app.ResetTrackerButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTrackerButtonPushed, true);
            app.ResetTrackerButton.Text = 'Reset Tracker';
            app.ZerooutazimuthButton = uibutton(app.TracklerControlButtonGridLayout, 'push');
            app.ZerooutazimuthButton.Text = 'Zero out azimuth';
            app.Button2 = uibutton(app.TracklerControlButtonGridLayout, 'push');
            app.Button2.Text = 'Button2';
            app.TrackercontrolLabel = uilabel(app.TrackerControlGridLayout);
            app.TrackercontrolLabel.Text = 'Tracker control';
            app.GridLayout3 = uigridlayout(app.MainGrid);
            app.TopInfoGridLayout = uigridlayout(app.GridLayout3);
            app.TrackerTargetAdditionalInfoGridLayout = uigridlayout(app.TopInfoGridLayout);
            app.TrackerOrientationGridLayout = uigridlayout(app.TrackerTargetAdditionalInfoGridLayout);
            app.TrackerOrientationValuesGridLayout = uigridlayout(app.TrackerOrientationGridLayout);
            app.YawEditFieldLabel = uilabel(app.TrackerOrientationValuesGridLayout);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.TrackerYawEditField = uieditfield(app.TrackerOrientationValuesGridLayout, 'numeric');
            app.TrackerYawEditField.ValueDisplayFormat = '%.3f°';
            app.TrackerYawEditField.Editable = 'off';
            app.RollEditFieldLabel = uilabel(app.TrackerOrientationValuesGridLayout);
            app.RollEditFieldLabel.Text = 'Roll';
            app.TrackerRollEditField = uieditfield(app.TrackerOrientationValuesGridLayout, 'numeric');
            app.TrackerRollEditField.ValueDisplayFormat = '%.3f °';
            app.TrackerRollEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.TrackerOrientationValuesGridLayout);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.TrackerPitchEditField = uieditfield(app.TrackerOrientationValuesGridLayout, 'numeric');
            app.TrackerPitchEditField.ValueDisplayFormat = '%.3f °';
            app.TrackerPitchEditField.Editable = 'off';
            app.TrackerorientationLabel = uilabel(app.TrackerOrientationGridLayout);
            app.TrackerorientationLabel.Text = 'Tracker orientation';
            app.TargetPositionGridLayout = uigridlayout(app.TrackerTargetAdditionalInfoGridLayout);
            app.TargetpositionLabel = uilabel(app.TargetPositionGridLayout);
            app.TargetpositionLabel.Text = 'Target position';
            app.GridLayout25_4 = uigridlayout(app.TargetPositionGridLayout);
            app.DistancestraightlineEditFieldLabel = uilabel(app.GridLayout25_4);
            app.DistancestraightlineEditFieldLabel.Text = 'Distance (straight line)';
            app.TargetDistancestraightlineEditField = uieditfield(app.GridLayout25_4, 'numeric');
            app.TargetDistancestraightlineEditField.ValueDisplayFormat = '%.3f m';
            app.ElevationEditField_2Label = uilabel(app.GridLayout25_4);
            app.ElevationEditField_2Label.Text = 'Elevation';
            app.TargetElevationEditField = uieditfield(app.GridLayout25_4, 'numeric');
            app.TargetElevationEditField.ValueDisplayFormat = '%.3f °';
            app.HeadingabsoluteEditFieldLabel = uilabel(app.GridLayout25_4);
            app.HeadingabsoluteEditFieldLabel.Text = 'Heading (absolute)';
            app.TargetHeadingabsoluteEditField = uieditfield(app.GridLayout25_4, 'numeric');
            app.TargetHeadingabsoluteEditField.ValueDisplayFormat = '%.3f °';
            app.HeadingrelativetotrackerEditFieldLabel = uilabel(app.GridLayout25_4);
            app.HeadingrelativetotrackerEditFieldLabel.Text = 'Heading (relative to tracker)';
            app.TargetHeadingrelativetotrackerEditField = uieditfield(app.GridLayout25_4, 'numeric');
            app.TargetHeadingrelativetotrackerEditField.ValueDisplayFormat = '%.3f °';
            app.PolarisationControlGridLayout = uigridlayout(app.TopInfoGridLayout);
            app.PolarisationcontrolLabel = uilabel(app.PolarisationControlGridLayout);
            app.PolarisationcontrolLabel.Text = 'Polarisation control';
            app.PolarisationControlValuesGridLayout = uigridlayout(app.PolarisationControlGridLayout);
            app.EditpolarisationEditFieldLabel = uilabel(app.PolarisationControlValuesGridLayout);
            app.EditpolarisationEditFieldLabel.Text = 'Edit polarisation';
            app.EditpolarisationEditField = uieditfield(app.PolarisationControlValuesGridLayout, 'numeric');
            app.EditpolarisationEditField.Limits = [0 360];
            app.EditpolarisationEditField.ValueDisplayFormat = '%.1d °';
            app.EditpolarisationEditField.ValueChangedFcn = createCallbackFcn(app, @EditpolarisationEditFieldValueChanged, true);
            app.MapGridLayout = uigridlayout(app.GridLayout3);
            app.MapPanel = uipanel(app.MapGridLayout);
            app.MapPanel.Title = 'Panel';
            app.GridLayout30 = uigridlayout(app.MapGridLayout);
            app.DisableMapAutoscaleButton = uibutton(app.GridLayout30, 'state');
            app.DisableMapAutoscaleButton.ValueChangedFcn = createCallbackFcn(app, @DisableMapAutoscaleButtonValueChanged, true);
            app.DisableMapAutoscaleButton.Text = 'Disable Map Autoscale';
            app.ManualcontrolTab = uitab(app.TabGroup);
            app.ManualcontrolTab.Title = 'Manual control';
            app.GridLayout22 = uigridlayout(app.ManualcontrolTab);
            app.GridLayout23 = uigridlayout(app.GridLayout22);
            app.GridLayout24 = uigridlayout(app.GridLayout23);
            app.GridLayout25 = uigridlayout(app.GridLayout24);
            app.GridLayout26 = uigridlayout(app.GridLayout25);
            app.GridLayout27 = uigridlayout(app.GridLayout26);
            app.ElevIncreaseButton = uibutton(app.GridLayout27, 'push');
            app.ElevIncreaseButton.ButtonPushedFcn = createCallbackFcn(app, @ElevIncreaseButtonPushed, true);
            app.ElevIncreaseButton.Enable = 'off';
            app.ElevIncreaseButton.Text = 'Elev +';
            app.YawDecreaseButton = uibutton(app.GridLayout27, 'push');
            app.YawDecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @YawDecreaseButtonPushed, true);
            app.YawDecreaseButton.Enable = 'off';
            app.YawDecreaseButton.Text = 'Yaw -';
            app.ElevDecreaseButton = uibutton(app.GridLayout27, 'push');
            app.ElevDecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @ElevDecreaseButtonPushed, true);
            app.ElevDecreaseButton.Enable = 'off';
            app.ElevDecreaseButton.Text = 'Elev -';
            app.YawIncreaseButton = uibutton(app.GridLayout27, 'push');
            app.YawIncreaseButton.ButtonPushedFcn = createCallbackFcn(app, @YawIncreaseButtonPushed, true);
            app.YawIncreaseButton.Enable = 'off';
            app.YawIncreaseButton.Text = 'Yaw +';
            app.ReturnHomeButton = uibutton(app.GridLayout27, 'push');
            app.ReturnHomeButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnHomeButtonPushed, true);
            app.ReturnHomeButton.Enable = 'off';
            app.ReturnHomeButton.Text = {'Return'; 'Home'};
            app.StepSizeButtonGroup = uibuttongroup(app.GridLayout26);
            app.StepSizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StepSizeButtonGroupSelectionChanged, true);
            app.StepSizeButtonGroup.Enable = 'off';
            app.StepSizeButtonGroup.Title = 'Step Size';
            app.Step1Button = uitogglebutton(app.StepSizeButtonGroup);
            app.Step1Button.Text = 'Step 1 °';
            app.Step1Button.Value = true;
            app.Step5Button = uitogglebutton(app.StepSizeButtonGroup);
            app.Step5Button.Text = 'Step 5 °';
            app.Step20Button = uitogglebutton(app.StepSizeButtonGroup);
            app.Step20Button.Text = 'Step 20 °';
            app.EnableManualControlButton = uibutton(app.GridLayout26, 'state');
            app.EnableManualControlButton.ValueChangedFcn = createCallbackFcn(app, @EnableManualControlButtonValueChanged, true);
            app.EnableManualControlButton.Text = {'Enable'; 'Manual'; 'Control'};
            app.GridLayout28 = uigridlayout(app.GridLayout25);
            app.GridLayout29 = uigridlayout(app.GridLayout28);
            app.Label = uilabel(app.GridLayout29);
            app.Label.Enable = 'off';
            app.Label.Text = 'Elev';
            app.ElevEditField = uieditfield(app.GridLayout29, 'numeric');
            app.ElevEditField.ValueDisplayFormat = '%11.4g °';
            app.ElevEditField.Editable = 'off';
            app.ElevEditField.Enable = 'off';
            app.GridLayout29_2 = uigridlayout(app.GridLayout28);
            app.AzEditFieldLabel = uilabel(app.GridLayout29_2);
            app.AzEditFieldLabel.Visible = 'off';
            app.AzEditFieldLabel.Text = 'Az';
            app.AzEditField = uieditfield(app.GridLayout29_2, 'numeric');
            app.AzEditField.ValueDisplayFormat = '%11.4g °';
            app.AzEditField.Editable = 'off';
            app.AzEditField.Enable = 'off';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end